classdef boxplot_viewer < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure               matlab.ui.Figure
		Menu                   matlab.ui.container.Menu
		OpenfileMenu           matlab.ui.container.Menu
		GridLayout             matlab.ui.container.GridLayout
		LeftPanel              matlab.ui.container.Panel
		DebugText              matlab.ui.control.TextArea
		DropDown_SelectMetric  matlab.ui.control.DropDown
		MetricLabel            matlab.ui.control.Label
		RightPanel             matlab.ui.container.Panel
		UIAxes                 matlab.ui.control.UIAxes
	end

	% Properties that correspond to apps with auto-reflow
	properties (Access = private)
		onePanelWidth = 576;
	end


	properties (Access = private)
		m_data % Description
		m_class
		m_lidarNames = ["Hmax_mean","Hmax_std","Hmax_min","Hmax_max",...
"Hmean_mean","Hmean_std","Hmean_min","Hmean_max",...
"Hmedian_mean","Hmedian_std","Hmedian_min","Hmedian_max",...
"Hp25_mean","Hp25_std","Hp25_min","Hp25_max",...
"Hp75_mean","Hp75_std","Hp75_min","Hp75_max",...
"Hp95_mean","Hp95_std","Hp95_min","Hp95_max",...
"PPR_mean","PPR_std","PPR_min","PPR_max",...
"DAM_z_mean","DAM_z_std","DAM_z_min","DAM_z_max",...
"BR_bellow_1_mean","BR_bellow_1_std","BR_bellow_1_min","BR_bellow_1_max",...
"BR_1_2_mean","BR_1_2_std","BR_1_2_min","BR_1_2_max",...
"BR_2_3_mean","BR_2_3_std","BR_2_3_min","BR_2_3_max",...
"BR_above_3_mean","BR_above_3_std","BR_above_3_min","BR_above_3_max",...
"BR_3_4_mean","BR_3_4_std","BR_3_4_min","BR_3_4_max",...
"BR_4_5_mean","BR_4_5_std","BR_4_5_min","BR_4_5_max",...
"BR_bellow_5_mean","BR_bellow_5_std","BR_bellow_5_min","BR_bellow_5_max",...
"BR_5_20_mean","BR_5_20_std","BR_5_20_min","BR_5_20_max",...
"BR_above_20_mean","BR_above_20_std","BR_above_20_min","BR_above_20_max",...
"Coeff_var_z_mean","Coeff_var_z_std","Coeff_var_z_min","Coeff_var_z_max",...
"Hkurt_mean","Hkurt_std","Hkurt_min","Hkurt_max",...
"Hskew_mean","Hskew_std","Hskew_min","Hskew_max",...
"Hstd_mean","Hstd_std","Hstd_min","Hstd_max",...
"Hvar_mean","Hvar_std","Hvar_min","Hvar_max",...
]
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Value changed function: DropDown_SelectMetric
		function DropDown_SelectMetricValueChanged(app, event)
			value = app.DropDown_SelectMetric.Value;

			n = find(contains(app.m_lidarNames, value));
			data = app.m_data(:,n);
			boxplot(app.UIAxes, data , app.m_class, 'Labels', {'91E0', '91F0','91G0','9110','Mono'})
			
			yticks(app.UIAxes, round(linspace(min(data), max(data), 10), 2))
			title(app.UIAxes, value, 'interpreter', 'none')
			

		end

		% Menu selected function: OpenfileMenu
		function OpenfileMenuSelected(app, event)
			[fileName, filePath] = uigetfile('*.csv', 'Select CSV file'); % .osm file path
			filePath = fullfile(filePath, fileName);

			data = readmatrix(filePath);
			app.m_class = data(:, end);
			app.m_data = data(:, 1:end-1);

			app.DebugText.Value = app.DebugText.Value + "File loaded\n";

			n = find(contains(app.m_lidarNames, app.DropDown_SelectMetric.Value));
			data = app.m_data(:,n);
			boxplot(app.UIAxes, data, app.m_class, 'Labels', {'91E0', '91F0','91G0','9110','Mono'})

			yticks(app.UIAxes, round(linspace(min(data), max(data), 10), 2))
			title(app.UIAxes, app.DropDown_SelectMetric.Value, 'interpreter', 'none')
			

		end

		% Changes arrangement of the app based on UIFigure width
		function updateAppLayout(app, event)
			currentFigureWidth = app.UIFigure.Position(3);
			if(currentFigureWidth <= app.onePanelWidth)
			    % Change to a 2x1 grid
			    app.GridLayout.RowHeight = {480, 480};
			    app.GridLayout.ColumnWidth = {'1x'};
			    app.RightPanel.Layout.Row = 2;
			    app.RightPanel.Layout.Column = 1;
			else
			    % Change to a 1x2 grid
			    app.GridLayout.RowHeight = {'1x'};
			    app.GridLayout.ColumnWidth = {219, '1x'};
			    app.RightPanel.Layout.Row = 1;
			    app.RightPanel.Layout.Column = 2;
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
			app.Menu = uimenu(app.UIFigure);
			app.Menu.Text = 'Menu';
			app.OpenfileMenu = uimenu(app.Menu);
			app.OpenfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenfileMenuSelected, true);
			app.OpenfileMenu.Accelerator = 'O';
			app.OpenfileMenu.Text = 'Open file';
			app.GridLayout = uigridlayout(app.UIFigure);
			app.LeftPanel = uipanel(app.GridLayout);
			app.MetricLabel = uilabel(app.LeftPanel);
			app.MetricLabel.Text = 'Metric:';
			app.DropDown_SelectMetric = uidropdown(app.LeftPanel);
			app.DropDown_SelectMetric.Items = {'Hmax_mean', 'Hmax_std', 'Hmax_min', 'Hmax_max', 'Hmean_mean', 'Hmean_std', 'Hmean_min', 'Hmean_max', 'Hmedian_mean', 'Hmedian_std', 'Hmedian_min', 'Hmedian_max', 'Hp25_mean', 'Hp25_std', 'Hp25_min', 'Hp25_max', 'Hp75_mean', 'Hp75_std', 'Hp75_min', 'Hp75_max', 'Hp95_mean', 'Hp95_std', 'Hp95_min', 'Hp95_max', 'PPR_mean', 'PPR_std', 'PPR_min', 'PPR_max', 'DAM_z_mean', 'DAM_z_std', 'DAM_z_min', 'DAM_z_max', 'BR_bellow_1_mean', 'BR_bellow_1_std', 'BR_bellow_1_min', 'BR_bellow_1_max', 'BR_1_2_mean', 'BR_1_2_std', 'BR_1_2_min', 'BR_1_2_max', 'BR_2_3_mean', 'BR_2_3_std', 'BR_2_3_min', 'BR_2_3_max', 'BR_above_3_mean', 'BR_above_3_std', 'BR_above_3_min', 'BR_above_3_max', 'BR_3_4_mean', 'BR_3_4_std', 'BR_3_4_min', 'BR_3_4_max', 'BR_4_5_mean', 'BR_4_5_std', 'BR_4_5_min', 'BR_4_5_max', 'BR_bellow_5_mean', 'BR_bellow_5_std', 'BR_bellow_5_min', 'BR_bellow_5_max', 'BR_5_20_mean', 'BR_5_20_std', 'BR_5_20_min', 'BR_5_20_max', 'BR_above_20_mean', 'BR_above_20_std', 'BR_above_20_min', 'BR_above_20_max', 'Coeff_var_z_mean', 'Coeff_var_z_std', 'Coeff_var_z_min', 'Coeff_var_z_max', 'Hkurt_mean', 'Hkurt_std', 'Hkurt_min', 'Hkurt_max', 'Hskew_mean', 'Hskew_std', 'Hskew_min', 'Hskew_max', 'Hstd_mean', 'Hstd_std', 'Hstd_min', 'Hstd_max', 'Hvar_mean', 'Hvar_std', 'Hvar_min', 'Hvar_max'};
			app.DropDown_SelectMetric.ValueChangedFcn = createCallbackFcn(app, @DropDown_SelectMetricValueChanged, true);
			app.DropDown_SelectMetric.Value = 'Hmax_mean';
			app.DebugText = uitextarea(app.LeftPanel);
			app.RightPanel = uipanel(app.GridLayout);
			app.UIAxes = uiaxes(app.RightPanel);
			title(app.UIAxes, 'Title')
			xlabel(app.UIAxes, 'Class')
			ylabel(app.UIAxes, 'Metric values')
			zlabel(app.UIAxes, 'Z')
			app.UIAxes.XTick = [];
			app.UIAxes.XTickLabel = '';
			app.UIAxes.YTick = [];
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = boxplot_viewer

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end